classdef execute < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Toolbar       matlab.ui.container.Toolbar
        EasyChoice    matlab.ui.container.toolbar.PushTool
        MediumChoice  matlab.ui.container.toolbar.PushTool
        HardChoice    matlab.ui.container.toolbar.PushTool
        GridLayout    matlab.ui.container.GridLayout
    end

    
    properties (Access = private)
        gridRow % Total number of rows in game
        gridColumn % Total number of columns in game
        gridMine % Total number of mines in game
    end
    
    methods (Access = private)
        
        function [] = initiateGrid(app)
            set(app.UIFigure, "visible", "off")
            disp("Running")
            r = app.gridRow;
            c = app.gridColumn;
            % Set up grid row and column width
            rowCell = [repmat({'1x'}, 1, r)];
            columnCell = [repmat({'1x'}, 1, c)];
            delete(app.GridLayout.Children)  % Must delete, if not going back to easy level the children will still be there
            app.GridLayout.RowHeight = rowCell;
            app.GridLayout.ColumnWidth = columnCell;
            % Create buttons for cells
            count = 1;
            for i = 1:r
                for j = 1:c
                    btnHandleStr = sprintf("app.Button%.0d", count);
                    count = count + 1;
                    btnHandle = str2func(btnHandleStr);
                    btnHandle = uibutton(app.GridLayout, 'push');
                    btnHandle.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
                    btnHandle.Text = "";
                    btnHandle.Layout.Row = i;
                    btnHandle.Layout.Column = j;
                end
            end
            disp("Done")
            app.UIFigure.Position = [100, 100, 30*c, 30*r]; % 30px per cell
            movegui(app.UIFigure, "center")
            app.GridLayout.RowSpacing = 0;
            app.GridLayout.ColumnSpacing = 0;
            app.GridLayout.Padding = [10, 10, 10, 10];
            set(app.UIFigure, "visible", "on")
        end

        function [rowChosen, columnChosen] = ButtonPushed(app, event)
            rowChosen = event.Source.Layout.Row;
            columnChosen = event.Source.Layout.Column;
            disp(rowChosen);
            disp(columnChosen);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           [app.gridRow, app.gridColumn, app.gridMine] = setDifficulty(2);
           set(app.UIFigure, "Name", "Kent's Minesweeper MATLAB")
           initiateGrid(app)
        end

        % Clicked callback: EasyChoice
        function EasyChoiceClicked(app, event)
            [app.gridRow, app.gridColumn, app.gridMine] = setDifficulty(1);
            initiateGrid(app)
        end

        % Clicked callback: MediumChoice
        function MediumChoiceClicked(app, event)
            [app.gridRow, app.gridColumn, app.gridMine] = setDifficulty(2);
            initiateGrid(app)
        end

        % Clicked callback: HardChoice
        function HardChoiceClicked(app, event)
            [app.gridRow, app.gridColumn, app.gridMine] = setDifficulty(3);
            initiateGrid(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.EasyChoice = uipushtool(app.Toolbar);
            app.EasyChoice.Tooltip = {'Easy'};
            app.EasyChoice.ClickedCallback = createCallbackFcn(app, @EasyChoiceClicked, true);
            app.EasyChoice.Icon = fullfile(pathToMLAPP, 'img', 'easy.svg');
            app.MediumChoice = uipushtool(app.Toolbar);
            app.MediumChoice.Tooltip = {'Medium'};
            app.MediumChoice.ClickedCallback = createCallbackFcn(app, @MediumChoiceClicked, true);
            app.MediumChoice.Icon = fullfile(pathToMLAPP, 'img', 'medium.svg');
            app.HardChoice = uipushtool(app.Toolbar);
            app.HardChoice.Tooltip = {'Hard'};
            app.HardChoice.ClickedCallback = createCallbackFcn(app, @HardChoiceClicked, true);
            app.HardChoice.Icon = fullfile(pathToMLAPP, 'img', 'hard.svg');
            app.GridLayout = uigridlayout(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = execute

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end